classdef randompass < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GeneratemultiplePanel          matlab.ui.container.Panel
        generateMultipleToggleButton   matlab.ui.control.StateButton
        generateMultipleAmountSpinner  matlab.ui.control.Spinner
        AmountSpinnerLabel             matlab.ui.control.Label
        lengthSlider                   matlab.ui.control.Slider
        OptionsPanel                   matlab.ui.container.Panel
        specialCharsOption             matlab.ui.control.CheckBox
        caseSensitivityOption          matlab.ui.control.CheckBox
        lettersOption                  matlab.ui.control.CheckBox
        digitsOption                   matlab.ui.control.CheckBox
        PresetsButtonGroup             matlab.ui.container.ButtonGroup
        CustomButton_2                 matlab.ui.control.RadioButton
        AssecureaspossibleURLsafeButton  matlab.ui.control.RadioButton
        AssecureaspossibleButton       matlab.ui.control.RadioButton
        PINcodeButton                  matlab.ui.control.RadioButton
        lengthSpinner                  matlab.ui.control.Spinner
        LengthLabel                    matlab.ui.control.Label
        GenerateButton                 matlab.ui.control.Button
        MiddlePanel                    matlab.ui.container.Panel
        ClearButton                    matlab.ui.control.Button
        HistoryLabel                   matlab.ui.control.Label
        history                        matlab.ui.control.TextArea
        StatusLabel                    matlab.ui.control.Label
        logs                           matlab.ui.control.TextArea
        YourrandomlygeneratedpasswordaccordingtopresetoptionsLabel  matlab.ui.control.Label
        passwordDisplay                matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    methods (Access = private)
        function password = generate(app)
            upperCase = ['A' 'B' 'C' 'D' 'E' 'F' 'G' 'H' 'I' 'J' 'K' 'L' 'M' 'N' 'O' 'P' 'Q' 'R' 'S' 'T' 'U' 'V' 'W' 'X' 'Y' 'Z'];
            lowerCase = ['a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'];
            digits = ['0' '1' '2' '3' '4' '5' '6' '7' '8' '9'];
            special = ['!' '@' '#' '$' '%' '^' '&' '*' '_' '-' '+' '=' '' ':' ';' '.' ','];
            password = '';

            for i = 1:app.lengthSpinner.Value
                char = '';
                while isempty(char)
                    rnd = randi([1 4], 1);
                    switch rnd
                        % numbers
                        case 1
                            if app.digitsOption.Value
                                char = digits(randi([1 numel(digits)], 1));
                            end
                        % lowercase letters
                        case 2
                            if app.lettersOption.Value
                                char = lowerCase(randi([1 numel(digits)], 1));
                            end
                        % uppercase letters
                        case 3
                            if app.caseSensitivityOption.Value
                                char = upperCase(randi([1 numel(digits)], 1));
                            end
                        % special characters
                        case 4
                            if app.specialCharsOption.Value
                                char = special(randi([1 numel(digits)], 1));
                            end
                    end
                end
                password = append(password, char);
            end
        end

        function logHistory(app, password, timeTaken)
            record = append(password, ' | ', num2str(timeTaken), 's');
            if isempty(char(app.history.Value))
                app.history.Value = record;
            else
                app.history.Value = [record; app.history.Value];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % STARTUP SETUP LOGIC
            app.UIFigure.Name = 'Random Password Generator v1.0 - by Petr Boháč';
            randomLength = randi([20 45], 1);
            app.lengthSpinner.Value = randomLength;
            app.lengthSlider.Value = randomLength;
        end

        % Selection changed function: PresetsButtonGroup
        function presetChange(app, event)
            % PRESET PROFILES
            selectedButton = app.PresetsButtonGroup.SelectedObject;
            if selectedButton.Text == "Custom"
                app.OptionsPanel.Enable = 'on';
                app.digitsOption.Value = false;
                app.lettersOption.Value = false;
                app.caseSensitivityOption.Value = false;
                app.caseSensitivityOption.Enable = 'off';
                app.specialCharsOption.Value = false;
                app.GenerateButton.Enable = 'off';
            else
                app.OptionsPanel.Enable = 'off';
                app.GenerateButton.Enable = 'on';
                switch selectedButton.Text
                    case 'PIN code'
                        app.digitsOption.Value = true;
                        app.lettersOption.Value = false;
                        app.caseSensitivityOption.Value = false;
                        app.caseSensitivityOption.Enable = 'off';
                        app.specialCharsOption.Value = false;
                    case 'As secure as possible'
                        app.digitsOption.Value = true;
                        app.lettersOption.Value = true;
                        app.caseSensitivityOption.Value = true;
                        app.caseSensitivityOption.Enable = 'on';
                        app.specialCharsOption.Value = true;
                    case 'As secure as possible (URL safe)'
                        app.digitsOption.Value = true;
                        app.lettersOption.Value = true;
                        app.caseSensitivityOption.Value = true;
                        app.caseSensitivityOption.Enable = 'on';
                        app.specialCharsOption.Value = false;
                end
            end
        end

        % Value changed function: lettersOption
        function lettersOptionsHandler(app, event)
            % LETTER OPTION HANDLER - ENABLES/DISABLES SUBOPTION +
            % optionChange() 
            optionChange(app, event);
            value = app.lettersOption.Value;
            if value == true
                app.caseSensitivityOption.Enable = 'on';
            else
                app.caseSensitivityOption.Enable = 'off';
                app.caseSensitivityOption.Value = false;
            end
        end

        % Button pushed function: GenerateButton
        function handleGenerate(app, event)
            % GENERATE BUTTON LOGIC
            if app.generateMultipleAmountSpinner.Enable == "on"
                amount = app.generateMultipleAmountSpinner.Value;
            else 
                amount = 1;
            end
            for i = 1:amount
                app.logs.Value = 'Generating new password...';
                start = tic();
                password = generate(app);
                timeTaken = toc(start);
                app.logs.Value = [app.logs.Value; 'Done'; append('Time elapsed: ', num2str(timeTaken), 's')];

                logHistory(app, password, timeTaken);

                app.passwordDisplay.Value = password;
            end
        end

        % Value changing function: lengthSlider
        function adaptlengthSliderValue(app, event)
            % PROJECT THE SLIDER VALUE TO THE SPINNER
            changingValue = event.Value;
            app.lengthSpinner.Value = changingValue;
        end

        % Value changed function: generateMultipleToggleButton
        function toggleGenerateMultiple(app, event)
            % GENERATE MULTIPLE OPTION MENU LOGIC
            value = app.generateMultipleToggleButton.Value;
            if value
                app.generateMultipleToggleButton.Text = 'Disable';
                app.generateMultipleAmountSpinner.Enable = 'on';
            else
                app.generateMultipleToggleButton.Text = 'Enable';
                app.generateMultipleAmountSpinner.Enable = 'off';
            end
        end

        % Button pushed function: ClearButton
        function clearHistory(app, event)
            app.history.Value = '';
        end

        % Value changed function: digitsOption, specialCharsOption
        function optionChange(app, event)
            % MAKES SURE THE GENERATE BUTTON IS DISABLED WITH NO OPTIONS
            % SELECTED
            digits = app.digitsOption.Value;
            letters = app.lettersOption.Value;
            specials = app.specialCharsOption.Value;
            if digits || letters || specials
                app.GenerateButton.Enable = 'on';
            else
                app.GenerateButton.Enable = 'off';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {582, 582};
                app.GridLayout.ColumnWidth = {'1x'};
                app.MiddlePanel.Layout.Row = 2;
                app.MiddlePanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {323, '1x'};
                app.MiddlePanel.Layout.Row = 1;
                app.MiddlePanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @handleGenerate, true);
            app.GenerateButton.Text = 'Generate';
            app.LengthLabel = uilabel(app.LeftPanel);
            app.LengthLabel.Text = 'Length';
            app.lengthSpinner = uispinner(app.LeftPanel);
            app.lengthSpinner.Limits = [1 Inf];
            app.lengthSpinner.RoundFractionalValues = 'on';
            app.lengthSpinner.Value = 5;
            app.PresetsButtonGroup = uibuttongroup(app.LeftPanel);
            app.PresetsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @presetChange, true);
            app.PresetsButtonGroup.Title = 'Presets';
            app.PINcodeButton = uiradiobutton(app.PresetsButtonGroup);
            app.PINcodeButton.Text = 'PIN code';
            app.PINcodeButton.Value = true;
            app.AssecureaspossibleButton = uiradiobutton(app.PresetsButtonGroup);
            app.AssecureaspossibleButton.Text = 'As secure as possible';
            app.AssecureaspossibleURLsafeButton = uiradiobutton(app.PresetsButtonGroup);
            app.AssecureaspossibleURLsafeButton.Text = 'As secure as possible (URL safe)';
            app.CustomButton_2 = uiradiobutton(app.PresetsButtonGroup);
            app.CustomButton_2.Text = 'Custom';
            app.OptionsPanel = uipanel(app.LeftPanel);
            app.OptionsPanel.Enable = 'off';
            app.OptionsPanel.Title = 'Options';
            app.digitsOption = uicheckbox(app.OptionsPanel);
            app.digitsOption.ValueChangedFcn = createCallbackFcn(app, @optionChange, true);
            app.digitsOption.Text = 'Digits';
            app.digitsOption.Value = true;
            app.lettersOption = uicheckbox(app.OptionsPanel);
            app.lettersOption.ValueChangedFcn = createCallbackFcn(app, @lettersOptionsHandler, true);
            app.lettersOption.Text = 'Letters';
            app.caseSensitivityOption = uicheckbox(app.OptionsPanel);
            app.caseSensitivityOption.Enable = 'off';
            app.caseSensitivityOption.Text = 'Case sensitivity';
            app.specialCharsOption = uicheckbox(app.OptionsPanel);
            app.specialCharsOption.ValueChangedFcn = createCallbackFcn(app, @optionChange, true);
            app.specialCharsOption.Text = 'Special characters';
            app.lengthSlider = uislider(app.LeftPanel);
            app.lengthSlider.Limits = [1 256];
            app.lengthSlider.ValueChangingFcn = createCallbackFcn(app, @adaptlengthSliderValue, true);
            app.lengthSlider.Value = 1;
            app.GeneratemultiplePanel = uipanel(app.LeftPanel);
            app.GeneratemultiplePanel.Title = 'Generate multiple';
            app.AmountSpinnerLabel = uilabel(app.GeneratemultiplePanel);
            app.AmountSpinnerLabel.Text = 'Amount ';
            app.generateMultipleAmountSpinner = uispinner(app.GeneratemultiplePanel);
            app.generateMultipleAmountSpinner.Limits = [1 Inf];
            app.generateMultipleAmountSpinner.RoundFractionalValues = 'on';
            app.generateMultipleAmountSpinner.Enable = 'off';
            app.generateMultipleAmountSpinner.Value = 1;
            app.generateMultipleToggleButton = uibutton(app.GeneratemultiplePanel, 'state');
            app.generateMultipleToggleButton.ValueChangedFcn = createCallbackFcn(app, @toggleGenerateMultiple, true);
            app.generateMultipleToggleButton.Text = 'Enable';
            app.MiddlePanel = uipanel(app.GridLayout);
            app.passwordDisplay = uieditfield(app.MiddlePanel, 'text');
            app.passwordDisplay.Editable = 'off';
            app.YourrandomlygeneratedpasswordaccordingtopresetoptionsLabel = uilabel(app.MiddlePanel);
            app.YourrandomlygeneratedpasswordaccordingtopresetoptionsLabel.Text = 'Your randomly generated password according to preset options';
            app.logs = uitextarea(app.MiddlePanel);
            app.logs.Editable = 'off';
            app.StatusLabel = uilabel(app.MiddlePanel);
            app.StatusLabel.Text = 'Status';
            app.history = uitextarea(app.MiddlePanel);
            app.history.Editable = 'off';
            app.HistoryLabel = uilabel(app.MiddlePanel);
            app.HistoryLabel.Text = 'History';
            app.ClearButton = uibutton(app.MiddlePanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearHistory, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = randompass

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end